classdef SPRob < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ShowtraceCheckBox  matlab.ui.control.CheckBox
        LoopLamp           matlab.ui.control.Lamp
        LoopLampLabel      matlab.ui.control.Label
        StopLoopButton     matlab.ui.control.Button
        StartLoopButton    matlab.ui.control.Button
        StopButton         matlab.ui.control.Button
        TurnrightButton    matlab.ui.control.Button
        TurnleftButton     matlab.ui.control.Button
        SpeedupButton      matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end

    % Private properties, DO NOT USE
    properties (Access = private)
        xLimit=40; % Set ground size X
        yLimit=40; % Set ground size Y
        MainLoopFlag = true; % Main loop on/off flag
        X = 20; % Robot location X
        Y = 20; % Robot location Y
        Theta=0; % Robot orientation theta
        yourX = 20; % Robot location X
        yourY = 20; % Robot location Y
        yourTheta=0 % Robot orientation theta
        
        Speed = 0; % Robot speed
        Rotation = 0; % Robot rotation
        RealSpeed = 0; % Robot speed
        RealRotation = 0; % Robot rotation
        trace = ones(100,2)*20;
        myanchors = zeros(5,2);
    end
    
    properties (Access = public)
        dt = 0.1; % Delta t, time step
        anchors=[0,0;0,20;20,0;20,30;10,10]; % Anchors location
    end
    
    methods (Access = public)
        % Get signals from anchors
        function signal=getanchorssignal(app)
            r = sqrt((app.anchors(:,1)-app.X).^2+(app.anchors(:,2)-app.Y).^2);
            phi = wrapToPi(atan2(app.anchors(:,2)-app.Y,app.anchors(:,1)-app.X)-app.Theta);
            [n,~] = size(app.anchors);
            signal = [r+0.5*rand(size(r)), phi+0.05*rand(size(phi)), [1:n]'];
        end
        % Speed up the robot
        function currentspeed = speedup(app)
            app.Speed=app.Speed+0.1;
            currentspeed=app.Speed;
        end
        % Rotate left the robot
        function currentrotation = leftspeedup(app)
            app.Rotation=app.Rotation+0.1;
            currentrotation=app.Rotation;
        end        
        % Rotate right the robot
        function currentrotation = rightspeedup(app)
            app.Rotation=app.Rotation-0.1;
            currentrotation=app.Rotation;
        end
        % Set the speed of the robot
        function currentspeed = setspeed(app,speed)
            app.Speed=speed;
            currentspeed=app.Speed;
        end 
        % Set the speed of the robot
        function currentspeed = setrotationspeed(app,rotationspeed)
            app.Rotation=rotationspeed;
            currentspeed=app.RealRotation;
        end 
        function updateyourAGV(app, x,y,theta)
            app.yourX = x;
            app.yourY = y;
            app.yourTheta = theta;
        end
        function updateyouranchors(app, anchors)
            app.myanchors = anchors;
        end
        % Update the simulation
        function update(app)
            app.updateAGV;            
            app.updateGraph;
        end
    end
    
    methods (Access = private)
        function h = circle(app,x,y,r)
            th = 0:pi/50:2*pi;
            xunit = r * cos(th) + x;
            yunit = r * sin(th) + y;
            h = plot(app.UIAxes,xunit, yunit);
        end
        function plotAGV(app)
            plot(app.UIAxes,[app.X,app.X+3*cos(app.Theta)],[app.Y,app.Y+3*sin(app.Theta)],'r-');
            hold(app.UIAxes,"on");
            circle(app,app.X,app.Y,3);
            hold(app.UIAxes,"off");
        end
        function plotYourAGV(app)
            plot(app.UIAxes,[app.yourX,app.yourX+3*cos(app.yourTheta)],[app.yourY,app.yourY+3*sin(app.yourTheta)],'g-');
            circle(app,app.yourX,app.yourY,3);
            text(app.UIAxes,app.X,app.Y,append('rotation ',num2str(app.Theta)));
        end
        function plotanchors(app)
            scatter(app.UIAxes,app.anchors(:,1),app.anchors(:,2),'black','o');
            for k=1:length(app.anchors(:,1))
                text(app.UIAxes,app.anchors(k,1)-0.4,app.anchors(k,2)-1.5,num2str(k),'color','blue')
                signal=app.getanchorssignal();
                text(app.UIAxes,app.anchors(k,1)-2.4,app.anchors(k,2)-3,num2str(signal(k)),'color','blue')
            end
            text(app.UIAxes,20,29,append('Real Rotation ',num2str(app.RealRotation)));
            text(app.UIAxes,20,31,append('Rotation ',num2str(app.Rotation)));
            text(app.UIAxes,20,33,append('Real Speed ',num2str(app.RealSpeed)));
            text(app.UIAxes,20,35,append('Speed ',num2str(app.Speed)));
        end
        function plotyouranchors(app)
            scatter(app.UIAxes,app.myanchors(:,1),app.myanchors(:,2),'red','o');
        end
        function plotTrace(app)
            plot(app.UIAxes,app.trace(1:5:end,1),app.trace(1:5:end,2));
        end
        function updateAGV(app)
            if(abs(app.Rotation)<0.001)
                app.RealSpeed = app.Speed+0.1*(rand()-0.5)*2;
                app.X = app.X + cos(app.Theta)*app.RealSpeed*app.dt;
                app.Y = app.Y + sin(app.Theta)*app.RealSpeed*app.dt;
            else
                app.RealSpeed = app.Speed+0.1*(rand()-0.5)*2;
                app.RealRotation = app.Rotation+0.08*(rand()-0.5)*2;
                r = app.RealSpeed/app.RealRotation;
                app.X = app.X - r*sin(app.Theta)+r*sin(app.Theta+app.RealRotation*app.dt);
                app.Y = app.Y + r*cos(app.Theta)-r*cos(app.Theta+app.RealRotation*app.dt);
                app.Theta = app.Theta + app.RealRotation*app.dt;
                if(app.Theta>pi)
                    app.Theta=app.Theta-2*pi;
                elseif(app.Theta<-pi)
                    app.Theta=app.Theta+2*pi;
                end
            end
            app.trace = [app.trace(2:end,:);app.X,app.Y];
        end
        function updateGraph(app)
            app.plotAGV;      
            hold(app.UIAxes,"on");
            app.plotYourAGV;
            app.plotanchors;
            app.plotyouranchors;
            if(app.ShowtraceCheckBox.Value)
                app.plotTrace();
            end
            drawnow
            hold(app.UIAxes,"off");
        end
        function initCanvas(app)
            app.plotAGV;
            hold(app.UIAxes,"on");
            app.plotanchors;
            hold(app.UIAxes,"off");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LoopLamp.Enable="off";
            app.initCanvas;
            app.UIAxes.XLim = [-5 app.xLimit];
            app.UIAxes.YLim = [-5 app.yLimit];
            axis(app.UIAxes, 'equal');
            
        end

        % Button pushed function: SpeedupButton
        function SpeedupButtonPushed(app, event)
            app.speedup;
        end

        % Button pushed function: StartLoopButton
        function StartLoopButtonPushed(app, event)
            app.LoopLamp.Enable="on";
            app.MainLoopFlag=true;
            while(app.MainLoopFlag)
                app.update();
                pause(0.1);
            end
        end

        % Button pushed function: StopLoopButton
        function StopLoopButtonPushed(app, event)
            app.LoopLamp.Enable="off";
            app.MainLoopFlag=false;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.Speed = 0;
            app.Rotation = 0;
        end

        % Button pushed function: TurnrightButton
        function TurnrightButtonPushed(app, event)
            app.rightspeedup();
        end

        % Button pushed function: TurnleftButton
        function TurnleftButtonPushed(app, event)
            app.leftspeedup();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SpeedupButton = uibutton(app.UIFigure, 'push');
            app.SpeedupButton.ButtonPushedFcn = createCallbackFcn(app, @SpeedupButtonPushed, true);
            app.SpeedupButton.Text = 'Speed up';
            app.TurnleftButton = uibutton(app.UIFigure, 'push');
            app.TurnleftButton.ButtonPushedFcn = createCallbackFcn(app, @TurnleftButtonPushed, true);
            app.TurnleftButton.Text = 'Turn left';
            app.TurnrightButton = uibutton(app.UIFigure, 'push');
            app.TurnrightButton.ButtonPushedFcn = createCallbackFcn(app, @TurnrightButtonPushed, true);
            app.TurnrightButton.Text = 'Turn right';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.StartLoopButton = uibutton(app.UIFigure, 'push');
            app.StartLoopButton.ButtonPushedFcn = createCallbackFcn(app, @StartLoopButtonPushed, true);
            app.StartLoopButton.Text = 'Start Loop';
            app.StopLoopButton = uibutton(app.UIFigure, 'push');
            app.StopLoopButton.ButtonPushedFcn = createCallbackFcn(app, @StopLoopButtonPushed, true);
            app.StopLoopButton.Text = 'Stop Loop';
            app.LoopLampLabel = uilabel(app.UIFigure);
            app.LoopLampLabel.Text = 'Loop';
            app.LoopLamp = uilamp(app.UIFigure);
            app.ShowtraceCheckBox = uicheckbox(app.UIFigure);
            app.ShowtraceCheckBox.Text = 'Show trace';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SPRob

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end